classdef DAQ_review < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        callingapp; %
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Dev_ctrl, log, data)
            % clamp mode "VC"  "CC"
            app.callingapp = Dev_ctrl;
            scan_rate_str = strfind(log.PatchClamp.ScanRate,'kHz');
            scan_rate = str2double(log.PatchClamp.ScanRate(1:scan_rate_str-1))*1000;
            time_trace = length(data.t)/scan_rate;
            clp_mode = log.PatchClamp.config.(log.PatchClamp.singleCh).clamp_mode;
            if strcmp(log.PatchClamp.singleCh,'ch2')
                app.UIFigure.Position = [562,530,561,424];
                app.UIAxes.Title.String = "Ch2 input";
                app.UIAxes2.Title.String = "Ch2 output";
                ao0 = data.ao1;
                ai0 = data.ai1;
            else
                app.UIAxes.Title.String = "Ch1 input";
                app.UIAxes2.Title.String = "Ch1 output";
                ao0 = data.ao0;
                ai0 = data.ai0;
            end
            ch = log.PatchClamp.singleCh;
            switch clp_mode
                case 'VC'
                    app.UIAxes.YLabel.String = 'Voltage / mV';
                    app.UIAxes2.YLabel.String = 'Current / pA';
                    R1 = log.PatchClamp.config.(ch).VC_R;
                    cmd = log.PatchClamp.config.(ch).VC_cmd;
                    VC_gain = log.PatchClamp.config.(ch).VC_gain;
                    switch R1(end)
                    case'M'
                        R1 = str2double(R1(1:end-1))*10^6;
                    case'G'
                        R1 = str2double(R1(1:end-1))*10^9;
                    end
                    plot(app.UIAxes2,data.t,ai0/R1/VC_gain*10^12,"red");
                        ymin = min(ai0)/R1/VC_gain*10^12;
                        ymax = max(ai0)/R1/VC_gain*10^12;
                case 'CC'
                    app.UIAxes.YLabel.String = 'Current / pA';
                    app.UIAxes2.YLabel.String = 'Voltage / mV';
                    CC_gain = log.PatchClamp.config.(ch).CC_gain;
                    cmd = log.PatchClamp.config.(ch).CC_cmd;
                    plot(app.UIAxes2,data.t,ai0*10^3/(log.PatchClamp.config.MemPoten*CC_gain),"red");
                    ymin = min(ai0)*10^3/(log.PatchClamp.config.MemPoten*CC_gain);
                    ymax = max(ai0)*10^3/(log.PatchClamp.config.MemPoten*CC_gain);
            end
            xlim(app.UIAxes,[0 time_trace]);
            xlim(app.UIAxes2,[0 time_trace]);
            ylim(app.UIAxes,[min(ao0)*cmd max(ao0)*cmd]);
            ylim(app.UIAxes2,[ymin,ymax])
            plot(app.UIAxes,data.t,ao0*cmd,"blue");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'input')
            xlabel(app.UIAxes, 'Time / s')
            ylabel(app.UIAxes, 'Voltage / v')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'output')
            xlabel(app.UIAxes2, 'Time / s')
            ylabel(app.UIAxes2, 'Voltage / v')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQ_review(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end