classdef DAQ_vis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp; % 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Dev_ctrl, queued_seg, ch_idx, clp_mode)
            % clamp mode "VC"  "CC"
            app.callingapp = Dev_ctrl;
            time_trace = length(queued_seg(:,1))/Dev_ctrl.NI_daq.daq_cam.Rate;
            if ch_idx == 2
                app.UIFigure.Position = [562,530,561,424];
                app.UIAxes.Title.String = "Ch2 input";
                app.UIAxes2.Title.String = "Ch2 output";
            else
                app.UIAxes.Title.String = "Ch1 input";
                app.UIAxes2.Title.String = "Ch1 output";
            end
            ao0 = queued_seg(:,1);
        switch clp_mode
            case 'VC'
                app.UIAxes.YLabel.String = 'Voltage / mV';
                app.UIAxes2.YLabel.String = 'Current / pA';
                if ch_idx == 2
%                     gain = Dev_ctrl.multi_clamp_para.ch2.VC_R;
                    cmd = Dev_ctrl.multi_clamp_para.ch2.VC_cmd;
                else
%                     gain = Dev_ctrl.multi_clamp_para.ch1.VC_R;
                    cmd = Dev_ctrl.multi_clamp_para.ch1.VC_cmd;
                end
            case 'CC'
                app.UIAxes.YLabel.String = 'Current / pA';
                app.UIAxes2.YLabel.String = 'Voltage / mV';
                if ch_idx == 2
%                     gain = Dev_ctrl.multi_clamp_para.ch2.CC_R;
                    cmd = Dev_ctrl.multi_clamp_para.ch2.CC_cmd;
                else
%                     gain = Dev_ctrl.multi_clamp_para.ch1.CC_R;
                    cmd = Dev_ctrl.multi_clamp_para.ch1.CC_cmd;
                end
        end
            try
                xlim(app.UIAxes,[0 time_trace]);
                xlim(app.UIAxes2,[0 time_trace]);
                ylim(app.UIAxes,[min(ao0)*cmd max(ao0)*cmd]);
            catch
            end
            hold(app.UIAxes, "on");
            hold(app.UIAxes2, "on");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'input')
            xlabel(app.UIAxes, 'Time / s')
            ylabel(app.UIAxes, 'Voltage / v')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'output')
            xlabel(app.UIAxes2, 'Time / s')
            ylabel(app.UIAxes2, 'Voltage / v')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQ_vis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end