classdef mem_confirm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        assignchannelButtonGroup  matlab.ui.container.ButtonGroup
        ch2Button                 matlab.ui.control.RadioButton
        ch1Button                 matlab.ui.control.RadioButton
        Label2                    matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        cancelButton              matlab.ui.control.Button
        ConfirmButton             matlab.ui.control.Button
    end


    properties (Access = private)
        callingapp %
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Dev_ctrl, mem_cam, mem_daq)
            app.callingapp = Dev_ctrl;
            app.Label.Text = strcat(num2str(round(mem_cam)),'Mb memory will be allocated for camera buffer');
            app.Label2.Text = strcat(num2str(round(mem_daq)),'Mb memory will be allocated for DAQ buffer');
            num_ch = length(app.callingapp.stimuluschannelsListBox.Items);
            if num_ch < 2
                app.assignchannelButtonGroup.Visible = "on";
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.callingapp.deployButton_2.UserData.memConfirm_wait = true;
            app.callingapp.deployButton_2.UserData.memConfirm_sele = true;
            num_ch = length(app.callingapp.stimuluschannelsListBox.Items);
            if num_ch < 2
                %                 if strcmp(app.assignchannelButtonGroup.SelectedObject.Text,'ch1')
                app.callingapp.deployButton_2.UserData.ch = app.assignchannelButtonGroup.SelectedObject.Text;
                %                 else
                %                     app.callingapp.deployButton_2.UserData.ch = 2;
                %                 end
            end
            %             delete(app);
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.callingapp.deployButton_2.UserData.memConfirm_wait = true;
            app.callingapp.deployButton_2.UserData.memConfirm_sele = false;
            %             delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.cancelButton = uibutton(app.UIFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'cancel';
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.assignchannelButtonGroup = uibuttongroup(app.UIFigure);
            app.assignchannelButtonGroup.Title = 'assign channel';
            app.assignchannelButtonGroup.Visible = 'off';
            app.ch1Button = uiradiobutton(app.assignchannelButtonGroup);
            app.ch1Button.Text = 'ch1';
            app.ch1Button.Value = true;
            app.ch2Button = uiradiobutton(app.assignchannelButtonGroup);
            app.ch2Button.Text = 'ch2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mem_confirm(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end